classdef Demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Gauge                           matlab.ui.control.SemicircularGauge
        ReadSwitchLabel                 matlab.ui.control.Label
        ReadSwitch                      matlab.ui.control.Switch
        CalibrateButton                 matlab.ui.control.Button
        FlexionangleEditField           matlab.ui.control.NumericEditField
        ModeSwitchLabel                 matlab.ui.control.Label
        ModeSwitch                      matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
        countedmovementsEditFieldLabel  matlab.ui.control.Label
        countedmovementsEditField       matlab.ui.control.NumericEditField
        ResetButton                     matlab.ui.control.Button
        RehabAppLabel                   matlab.ui.control.Label
        ExerciseDropDownLabel           matlab.ui.control.Label
        ExerciseDropDown                matlab.ui.control.DropDown
        SelfCalibrationSwitchLabel      matlab.ui.control.Label
        SelfCalibrationSwitch           matlab.ui.control.RockerSwitch
        FlexionAngleLabel               matlab.ui.control.Label
    end

    
    properties (Access = public)
        demoSignal = cell2mat(struct2cell(load('angle_method2.mat',"angleFINAL"))); % Description
        %demoSignal = [1:10000]
        flexAngle = 0;
        plottedAngle = 0;
        filteredAngle = 0;
        calibration = 0;
        countedMovements = 0;
        selfCalibrate = 0;
        flag = 0;
        ii = 0;
        stop = 0;
    end
           
    methods (Access = private)
        
        function appModality(app)
  
            value = app.ModeSwitch.Value;
            
            switch value
                case 'Demo' 
                    while app.ii<length(app.demoSignal) && app.stop == 0
                    
                    app.ii = app.ii + 1;
                    app.flexAngle = app.demoSignal(app.ii) ;
                                                     
                    app.flexAngle = app.flexAngle - app.calibration;
                    % Self calibration
                   
                    if app.flexAngle < 0 && app.selfCalibrate == 1
                        app.calibration = app.flexAngle;
                        app.flexAngle = app.flexAngle - app.calibration;
                    end
                
                    
                     app.plottedAngle = [app.plottedAngle; app.flexAngle];
                     app.filteredAngle = [app.filteredAngle; (0.9*app.filteredAngle(app.ii) + 0.1*app.flexAngle)];
                    app.Gauge.Value = app.flexAngle;
                    app.FlexionangleEditField.Value = app.flexAngle ;
                     app.movementPlots;
                    pause(0.02);
                       
                    end 
            end
        end


        function movementPlots(app)
            switch app.stop
                case 0
                    if app.plottedAngle(app.ii) > 1.4*app.filteredAngle(app.ii) && app.flag == 0
                        app.countedMovements = app.countedMovements + 1; % Doesn't take into account classification
                        app.flag = 1;
                    end
                    if app.plottedAngle(app.ii) < 1.4*app.filteredAngle(app.ii) && app.flag == 1
                        app.flag = 0;
                    end
                    app.countedmovementsEditField.Value = app.countedMovements;
                    ax = app.UIAxes;
                    if app.ii < 150 
                        plot(ax,app.plottedAngle(1:app.ii))
                    else 
                        plot(ax,app.plottedAngle(app.ii-150+1:app.ii))
                    end
                    
            end
  
        end 
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReadSwitch
        function ReadSwitchValueChanged(app, event)
            value = app.ReadSwitch.Value;
             
            switch value
                case 'Off'
                    set(app.CalibrateButton,'enable','off');
                    app.stop = 1;
                    app.Gauge.Value = 0 ;
                    app.FlexionangleEditField.Value = 0;
                    app.ii = 0;
                    
                case 'On'
                    set(app.CalibrateButton,'enable','on');
                    app.stop = 0;
                    app.appModality
                    
            end
            
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            app.calibration = app.flexAngle;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.countedMovements = 0;
            app.countedmovementsEditField.Value = app.countedMovements;
        end

        % Value changed function: countedmovementsEditField
        function countedmovementsEditFieldValueChanged(app, event)
            value = app.countedmovementsEditField.Value;
            app.countedMovements = value;
        end

        % Value changed function: SelfCalibrationSwitch
        function SelfCalibrationSwitchValueChanged(app, event)
            value = app.SelfCalibrationSwitch.Value;
            switch value
                case 'On'
                    app.selfCalibrate = 1
                case 'Off'
                    app.selfCalibrate = 0
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [0 180];
            app.Gauge.MajorTicks = [0 30 60 90 120 150 180];
            app.Gauge.MajorTickLabels = {'0', '30', '60', '90', '120', '150', '180'};
            app.ReadSwitchLabel = uilabel(app.UIFigure);
            app.ReadSwitchLabel.Text = 'Read';
            app.ReadSwitch = uiswitch(app.UIFigure, 'slider');
            app.ReadSwitch.ValueChangedFcn = createCallbackFcn(app, @ReadSwitchValueChanged, true);
            app.CalibrateButton = uibutton(app.UIFigure, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Enable = 'off';
            app.CalibrateButton.Text = 'Calibrate';
            app.FlexionangleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ModeSwitchLabel = uilabel(app.UIFigure);
            app.ModeSwitchLabel.Text = 'Mode';
            app.ModeSwitch = uiswitch(app.UIFigure, 'slider');
            app.ModeSwitch.Items = {'Demo', 'On'};
            app.ModeSwitch.Value = 'Demo';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Flexion Angle')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'angle (deg)')
            app.UIAxes.XTick = [0 50 100 150];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'};
            app.UIAxes.YTick = [0 45 90 135 180];
            app.UIAxes.YTickLabel = {'0'; '45'; '90'; '135'; '180'};
            app.countedmovementsEditFieldLabel = uilabel(app.UIFigure);
            app.countedmovementsEditFieldLabel.Text = 'counted movements';
            app.countedmovementsEditField = uieditfield(app.UIFigure, 'numeric');
            app.countedmovementsEditField.ValueChangedFcn = createCallbackFcn(app, @countedmovementsEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RehabAppLabel = uilabel(app.UIFigure);
            app.RehabAppLabel.Text = 'RehabApp';
            app.ExerciseDropDownLabel = uilabel(app.UIFigure);
            app.ExerciseDropDownLabel.Text = 'Exercise';
            app.ExerciseDropDown = uidropdown(app.UIFigure);
            app.ExerciseDropDown.Items = {'Squat', 'Leg extension', 'Walk - run'};
            app.ExerciseDropDown.Value = 'Squat';
            app.SelfCalibrationSwitchLabel = uilabel(app.UIFigure);
            app.SelfCalibrationSwitchLabel.Text = 'Self Calibration';
            app.SelfCalibrationSwitch = uiswitch(app.UIFigure, 'rocker');
            app.SelfCalibrationSwitch.ValueChangedFcn = createCallbackFcn(app, @SelfCalibrationSwitchValueChanged, true);
            app.FlexionAngleLabel = uilabel(app.UIFigure);
            app.FlexionAngleLabel.Text = 'Flexion Angle';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end